classdef ManagmentGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow          matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadlistfromfileMenu  matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout9           matlab.ui.container.GridLayout
        GridLayout8           matlab.ui.container.GridLayout
        BrowseButton          matlab.ui.control.Button
        LogoEditField         matlab.ui.control.EditField
        LogoEditFieldLabel    matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        LogoImage             matlab.ui.control.Image
        GridLayout6           matlab.ui.container.GridLayout
        GridLayout7           matlab.ui.container.GridLayout
        DeleteButton          matlab.ui.control.Button
        AddButton             matlab.ui.control.Button
        RestaurantsListBox    matlab.ui.control.ListBox
        TextArea              matlab.ui.control.TextArea
        GridLayout2           matlab.ui.container.GridLayout
        CancelButton          matlab.ui.control.Button
        SaveandCloseButton    matlab.ui.control.Button
        LogoContextMenu       matlab.ui.container.ContextMenu
        DeletelogoMenu        matlab.ui.container.Menu
    end

    properties (Access = private)
        parent WheelOfFortuneCookies.MainGUI {mustBeScalarOrEmpty}
        restaurants (1,:) WheelOfFortuneCookies.Restaurant.Restaurant
    end

    properties (Constant,Access = private)
        NO_LOGO_TEXT = "[No logo image file selected]"
    end
    
    methods (Access = private)
        function success = loadLogoImage(app,imageFilename)
            arguments (Input)
                app (1,1) WheelOfFortuneCookies.Restaurant.ManagmentGUI
                imageFilename
            end

            success = false;
            app.LogoEditField.FontColor = 'k';
            try
                app.LogoEditField.Value = imageFilename;
                app.LogoImage.ImageSource = imageFilename;
                success = true;
            catch
                app.LogoEditField.FontColor = 'r';
                app.LogoImage.ImageSource = "";

                uialert(app.FigureWindow,["The selected logo image could not be found. Select ",...
                    "a different file and try again."],"Logo Image File Not Found");
            end
            
        end

        function fillRestaurantsListBox(app)
            arguments (Input)
                app (1,1) WheelOfFortuneCookies.Restaurant.ManagmentGUI
            end

            if isempty(app.restaurants)
                app.RestaurantsListBox.Items = {};
                app.RestaurantsListBox.ItemsData = [];
            else
                app.sortRestaurantsByName;
                
                app.RestaurantsListBox.Items = [app.restaurants.name];
                app.RestaurantsListBox.ItemsData = app.restaurants;
                app.RestaurantsListBox.Value = app.restaurants(1);
            end
            app.RestaurantsListBoxValueChanged;
        end

        function sortRestaurantsByName(app)
            arguments (Input)
                app (1,1) WheelOfFortuneCookies.Restaurant.ManagmentGUI
            end

            if ~isempty(app.restaurants)
                [~,sortOrder] = sort(upper([app.restaurants.name]));
                app.restaurants = app.restaurants(sortOrder);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, parentRestaurants)
            app.parent = parent;
            for i = 1:numel(parentRestaurants)
                app.restaurants(i) = WheelOfFortuneCookies.Restaurant.Restaurant(name=parentRestaurants(i).name,...
                    logoImageFilename=parentRestaurants(i).logoImageFilename);
            end

            app.fillRestaurantsListBox;

            app.LogoEditField.Value = app.NO_LOGO_TEXT;

            movegui(app.FigureWindow,"center");
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveandCloseButton
        function SaveandCloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Get the file using the built in file selecting GUI.
            [filename,pathname] = uigetfile({'*.png;*.bmp;*.jpg;*.jpeg','Image Files'},"Select Logo Image File");
            % Prevent the GUIs from being hidden behind the MATLAB window.
            figure(app.parent.FigureWindow);
            figure(app.FigureWindow);
            
            % If a file was selected, process the input.
            if ischar(filename) && ischar(pathname)
                % Create the full filename by concatenating the path and filename.
                imageFilename = fullfile(pathname,filename);

                imageLoaded = app.loadLogoImage(imageFilename);

                if imageLoaded
                    app.RestaurantsListBox.Value.logoImageFilename = imageFilename;
                end
            end
        end

        % Menu selected function: DeletelogoMenu
        function DeletelogoMenuSelected(app, event)
            app.LogoEditField.Value = app.NO_LOGO_TEXT;
            app.LogoImage.ImageSource = '';
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.NameEditField.Value = strtrim(app.NameEditField.Value);
            Restaurant = app.RestaurantsListBox.Value;
            Restaurant.name = app.NameEditField.Value;
            app.fillRestaurantsListBox;
            app.RestaurantsListBox.Value = Restaurant;
            app.RestaurantsListBoxValueChanged;
        end

        % Value changed function: RestaurantsListBox
        function RestaurantsListBoxValueChanged(app, event)
            Restaurant = app.RestaurantsListBox.Value;
            
            app.DeleteButton.Enable = ~isempty(Restaurant);

            app.NameEditField.Enable = ~isempty(Restaurant) && isscalar(Restaurant);
            app.LogoEditField.Enable = ~isempty(Restaurant) && isscalar(Restaurant);
            app.BrowseButton.Enable = ~isempty(Restaurant) && isscalar(Restaurant);
            
            if isempty(Restaurant)
                app.NameEditField.Value = "[No restaurant selected]";
                app.LogoEditField.Value = "[No restaurant selected]";
                app.LogoImage.ImageSource = "";
                return
            elseif ~isscalar(Restaurant)
                app.NameEditField.Value = "[Multiple restaurants selected]";
                app.LogoEditField.Value = "[Multiple restaurants selected]";
                app.LogoImage.ImageSource = "";
                return
            end

            app.NameEditField.Value = Restaurant.name;
            if Restaurant.logoImageFilename ~= ""
                app.loadLogoImage(Restaurant.logoImageFilename);
            else
                app.LogoEditField.Value = app.NO_LOGO_TEXT;
                app.LogoImage.ImageSource = "";
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            deleteInds = arrayfun(@(R)find(R==app.restaurants),app.RestaurantsListBox.Value);
            
            restaurantNames = strjoin([app.restaurants(deleteInds).name],"\n• ");
            restaurantNames = sprintf(strcat("\n• ",restaurantNames));

            dlgAns = uiconfirm(app.FigureWindow,...
                sprintf("Are you sure you'd like to delete the following restaurants?\n%s",...
                restaurantNames(:)),"Confirm Delete Restaurants",Options=["Yes","No"],...
                DefaultOption="No");

            if dlgAns == "No"
                return
            end

            delete(app.restaurants(deleteInds))
            app.restaurants(deleteInds) = [];

            app.fillRestaurantsListBox;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            Restaurant = WheelOfFortuneCookies.Restaurant.Restaurant(name="New restaurant");
            app.restaurants(end+1) = Restaurant;

            app.fillRestaurantsListBox;
            app.RestaurantsListBox.Value = Restaurant;
            app.RestaurantsListBoxValueChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Restaurant Management';
            app.FileMenu = uimenu(app.FigureWindow);
            app.FileMenu.Text = 'File';
            app.LoadlistfromfileMenu = uimenu(app.FileMenu);
            app.LoadlistfromfileMenu.Text = 'Load list from file...';
            app.GridLayout = uigridlayout(app.FigureWindow);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveandCloseButton = uibutton(app.GridLayout2, 'push');
            app.SaveandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCloseButtonPushed, true);
            app.SaveandCloseButton.Text = 'Save and Close';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'[Instructions]'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.RestaurantsListBox = uilistbox(app.GridLayout6);
            app.RestaurantsListBox.Multiselect = 'on';
            app.RestaurantsListBox.ValueChangedFcn = createCallbackFcn(app, @RestaurantsListBoxValueChanged, true);
            app.RestaurantsListBox.Value = {'Item 1'};
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.AddButton = uibutton(app.GridLayout7, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout7, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.LogoImage = uiimage(app.GridLayout9);
            app.GridLayout8 = uigridlayout(app.GridLayout9);
            app.NameEditFieldLabel = uilabel(app.GridLayout8);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout8, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.LogoEditFieldLabel = uilabel(app.GridLayout8);
            app.LogoEditFieldLabel.Text = 'Logo';
            app.LogoEditField = uieditfield(app.GridLayout8, 'text');
            app.LogoEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.GridLayout8, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.LogoContextMenu = uicontextmenu(app.FigureWindow);
            app.DeletelogoMenu = uimenu(app.LogoContextMenu);
            app.DeletelogoMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletelogoMenuSelected, true);
            app.DeletelogoMenu.Text = 'Delete logo';
            % Assign app.LogoContextMenu
            app.LogoEditField.ContextMenu = app.LogoContextMenu;
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManagmentGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FigureWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FigureWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end