classdef WheelOfFortuneCookiesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow        matlab.ui.Figure
        WindowGrid          matlab.ui.container.GridLayout
        MainGrid            matlab.ui.container.GridLayout
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        EditField           matlab.ui.control.EditField
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        CanesLabel          matlab.ui.control.Label
        WheelAxes           matlab.ui.control.UIAxes
        BottomControlsGrid  matlab.ui.container.GridLayout
        VersionHyperlink    matlab.ui.control.Hyperlink
        CloseButton         matlab.ui.control.Button
        VersionPrefixLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Restaurants (1,:) restaurant
        Wheel spinnerWheel {mustBeScalarOrEmpty}
    end

    properties (Dependent, Access = private)
        nRestaurants (1,1) double
    end

    properties (Constant, Access = private)
        nPtsCirc = 360;
    end

    methods
        function out = get.nRestaurants(app)
            out = numel(app.Restaurants);
        end
    end
    
    methods (Access = private)
        function addRestaurant(app,name)
            arguments
                app (1,1) WheelOfFortuneCookiesGUI
                name (1,:) string
            end

            for ii = 1:numel(name)
                app.Restaurants(app.nRestaurants+1) = restaurant(name=name(ii));
            end
        end

        function drawWheel(app)
            app.Wheel.draw([app.Restaurants.nVotes],[app.Restaurants.name]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addRestaurant(["Cane's","Punch Pizza","Afro Deli"]);
            app.Restaurants(1).addVote(2);
            app.Restaurants(2).addVote(1);
            app.Restaurants(3).addVote(1);

            app.Wheel = spinnerWheel(app.WheelAxes);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.drawWheel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Wheel of Fortune Cookies';
            app.WindowGrid = uigridlayout(app.FigureWindow);
            app.BottomControlsGrid = uigridlayout(app.WindowGrid);
            app.VersionPrefixLabel = uilabel(app.BottomControlsGrid);
            app.VersionPrefixLabel.Text = 'Version:';
            app.CloseButton = uibutton(app.BottomControlsGrid, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.VersionHyperlink = uihyperlink(app.BottomControlsGrid);
            app.VersionHyperlink.Tooltip = {'Click here to go to the GitHub repository with the newest version.'};
            app.VersionHyperlink.URL = 'https://github.com/ThatMechEGuy/WheelOfFortuneCookies';
            app.VersionHyperlink.Text = '2022.12';
            app.MainGrid = uigridlayout(app.WindowGrid);
            app.WheelAxes = uiaxes(app.MainGrid);
            title(app.WheelAxes, 'Title')
            xlabel(app.WheelAxes, 'X')
            ylabel(app.WheelAxes, 'Y')
            zlabel(app.WheelAxes, 'Z')
            app.GridLayout = uigridlayout(app.MainGrid);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CanesLabel = uilabel(app.GridLayout2);
            app.CanesLabel.Text = 'Cane''s';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.Text = '-';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Value = '+2';
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelOfFortuneCookiesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end